classdef CMApI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        ILabel                      matlab.ui.control.Label
        pLabel                      matlab.ui.control.Label
        CMALabel                    matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        ImportMapTab                matlab.ui.container.Tab
        ImportMapButton             matlab.ui.control.Button
        CompressorTab               matlab.ui.container.Tab
        Switch                      matlab.ui.control.Switch
        SwitchLabel                 matlab.ui.control.Label
        NewSpeedCmpEditField        matlab.ui.control.NumericEditField
        InterpolatenewspeedLabel    matlab.ui.control.Label
        UITableCmp                  matlab.ui.control.Table
        SaveButtonCmp               matlab.ui.control.Button
        ResetButtonCmp              matlab.ui.control.Button
        InterpolateButtonCmp        matlab.ui.control.Button
        DropDownCmp                 matlab.ui.control.DropDown
        UIAxesCmp                   matlab.ui.control.UIAxes
        TurbineTab                  matlab.ui.container.Tab
        NewSpeedTrbEditField        matlab.ui.control.NumericEditField
        InterpolatenewspeedLabel_2  matlab.ui.control.Label
        InterpolateButtonTrb        matlab.ui.control.Button
        SaveButtonTrb               matlab.ui.control.Button
        ResetButtonTrb              matlab.ui.control.Button
        DropDownTrb                 matlab.ui.control.DropDown
        UITableTrb                  matlab.ui.control.Table
        UIAxesTrb                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDownCmp
        function DropDownCmpValueChanged(app, event)
            value = app.DropDownCmp.Value;
            % if value == '400N'
            %     load("Maps400N.mat")
            % end

            % Loading map
            if value == '1000N'
                load("Maps1kN.mat")
            end

            % Saving variables in the workspace for further use in the code
            assignin("base",'Wp2',Wp2); % compressor mass flow
            assignin("base",'NcPct2',NcPct2); % corrected speed (N/sqrt(T2/Tstd))
            assignin("base",'Rc',Rc); % pressure ratio
            assignin("base",'effc',effc); % efficiency
            assignin("base",'Nrpm2',Nrpm2); % Actual speed in rpm
            assignin("base",'Np2',Np2); % Partially corrected speed (speed param) N/sqrt(T2)

            % Compressor map plot
            contourf(app.UIAxesCmp, Wp2, Rc, effc,linspace(0,1.0,50),'LineColor', 'none');
            app.UIAxesCmp.CLim =[0.4, 0.9];
            c = colorbar(app.UIAxesCmp);
            app.UIAxesCmp.Box = 'on';
            colormap(app.UIAxesCmp, 'jet');
            c.Label.String = 'Efficiency';
            hold(app.UIAxesCmp, 'on'); 
            plot(app.UIAxesCmp, Wp2', Rc',  '-k','LineWidth',1)
            xlabel(app.UIAxesCmp, '$W_{p2} = W_2\sqrt(T_2)/P_2$ $[kg/s\cdot K^{0.5}/Pa]$ ','Interpreter','latex');
            ylabel(app.UIAxesCmp, '$Rc = P_3/P_2$','Interpreter','latex');
            % hold(app.UIAxesCmp, 'off');
            for i = 1:length(NcPct2)
                text(app.UIAxesCmp,Wp2(i,1),Rc(i,1)-0.2,num2str(round(NcPct2(i),0)),'FontSize', 12, 'Color', 'black', 'HorizontalAlignment', 'center');
            end


            % Turbine
            assignin("base",'Wp4',Wp4); % turbine air mass flow
            assignin("base",'NcPct4',NcPct4); % turbine corrected speed?
            assignin("base",'Rt',Rt); % pressure ratio
            assignin("base",'efft',efft); % efficiency
            assignin("base",'Nrpm4',Nrpm4); % ?
            assignin("base",'Np4',Np4); % ?

            % Turbine map plot
            contourf(app.UIAxesTrb, Wp4.*Np4',Rt,efft,'LineColor', 'none');
            c = colorbar(app.UIAxesTrb);
            app.UIAxesTrb.Box = 'on';
            colormap(app.UIAxesTrb, 'jet');
            c.Label.String = 'Efficiency';
            hold(app.UIAxesTrb, 'on'); 
            plot(app.UIAxesTrb, Wp4.*Np4',Rt,  '.k','MarkerSize',1)
            ylabel(app.UIAxesTrb,'$Rt=P_4/P_5$','Interpreter','latex');
            xlabel(app.UIAxesTrb,'$W_{p4} \cdot Np4$ $[kg\cdot rpm/s\cdot K/Pa^{2}]$ ','Interpreter','latex');
            % hold(app.UIAxesTrb, 'off'); 

        end

        % Callback function: InterpolateButtonCmp, NewSpeedCmpEditField, 
        % ...and 1 other component
        function InterpolateButtonPushed(app, event)
            speed = app.NewSpeedCmpEditField.Value;

            Wp2 = evalin('base', 'Wp2');
            Rc = evalin('base', 'Rc');
            effc = evalin('base', 'effc');
            NcPct2 = evalin('base', 'NcPct2');            
            Nrpm2 = evalin('base', 'Nrpm2');
            Np2 = evalin('base', 'Np2');

            if ~ismember(speed, NcPct2) % If inserted speed line is not originally in the map
                
                % Interpolate to obtain new speed line values
                Wp2_new = interp1(NcPct2, Wp2, speed);
                Rc_new = interp1(NcPct2,Rc,speed);
                effc_new = interp1(NcPct2,effc,speed);

                % Plot new speed line
                plot(app.UIAxesCmp, Wp2_new', Rc_new',  '-r','LineWidth',1)
                text(app.UIAxesCmp,Wp2_new(1,1),Rc_new(1,1)-0.2,num2str(speed),'FontSize', 12, 'Color', 'red', 'HorizontalAlignment', 'center');
                
                % Create table with new speed line values
                newData = table(Wp2_new', Rc_new', effc_new', 'VariableNames', {'Wp2', 'Rc', 'effc'});
                app.UITableCmp.Data = newData;

                % Find where to insert new speed line within original speed lines
                insertionNIndex = find(NcPct2 >= speed, 1);

                % Insert new speed line within original vectors
                NcPct2  = [NcPct2(1:insertionNIndex-1), speed, NcPct2(insertionNIndex:end)];
                Nrpm2   = [Nrpm2(1:insertionNIndex-1), speed*Nrpm2(insertionNIndex-1)/NcPct2(insertionNIndex-1), Nrpm2(insertionNIndex:end)];
                Np2     = [Np2(1:insertionNIndex-1), Nrpm2(insertionNIndex)/(Nrpm2(insertionNIndex-1)/Np2(insertionNIndex-1)), Np2(insertionNIndex:end)];
                Wp2 = [Wp2(1:insertionNIndex-1, :); Wp2_new; Wp2(insertionNIndex:end, :)];
                Rc = [Rc(1:insertionNIndex-1, :); Rc_new; Rc(insertionNIndex:end, :)];
                effc = [effc(1:insertionNIndex-1, :); effc_new; effc(insertionNIndex:end, :)];

                % Save updates map values (to include new speed line)
                assignin("base",'Wp2',Wp2);
                assignin("base",'NcPct2',NcPct2);
                assignin("base",'Rc',Rc);
                assignin("base",'effc',effc);
                assignin("base",'Nrpm2',Nrpm2);
                assignin("base",'Np2',Np2);

                save('CmpMapUpdated.mat',"Wp2","effc","Rc","NcPct2","Np2","Nrpm2")
            end 
        end

        % Button pushed function: SaveButtonCmp
        function SaveButtonCmpPushed(app, event)
            if app.DropDownCmp.Value == '1000N'
                load("Maps1kN.mat")
            end
            load('CmpMapUpdated.mat')
            save('NewMap.mat','Wp7', 'betac', 'betat', 'effc', 'efft', 'NcPct2', 'NcPct4', 'Np2', 'Np4', 'Nrpm2', 'Nrpm4', 'Rc', 'Rc_sl', 'Rn', 'Rt', 'Wp2', 'Wp2_sl', 'Wp4')
        end

        % Button pushed function: ResetButtonCmp
        function ResetButtonCmpPushed(app, event)
            % cla(app.UIAxesCmp);  % Clear the axes
            app.UITableCmp.Data = [];  % Clear the table
            app.NewSpeedCmpEditField.Value = 0;  % Reset the X edit field
            DropDownCmpValueChanged(app)
        end

        % Button pushed function: ImportMapButton
        function ImportMapButtonPushed(app, event)
            % Open a file selection dialog to choose a CSV, Excel, or MAT file
            [fileName, pathName] = uigetfile({'*.csv;*.xls;*.xlsx;*.mat', 'Data Files (*.csv, *.xls, *.xlsx, *.mat)'},'Select a Data File');
            
            % Check if a file was selected
            if isequal(fileName, 0)
                disp('No file selected');
                return;
            end
            
            % Construct the full file path
            fullFileName = fullfile(pathName, fileName);
            
            % Determine the file extension
            [~, ~, ext] = fileparts(fullFileName);
            
            % Initialize a variable to store imported data
            importedData = [];
        
            % Read the file based on its extension
            switch lower(ext)
                case '.csv'
                    % Read CSV file into a table
                    importedData = readtable(fullFileName);
                    
                case {'.xls', '.xlsx'}
                    % Read Excel file into a table
                    importedData = readtable(fullFileName);
                    
                case '.mat'
                    % Load MAT file into a struct
                    matData = load(fullFileName);
                    fieldNames = fieldnames(matData);
                    if ~isempty(fieldNames)
                        importedData = matData.(fieldNames{1});  % Get the first variable from the MAT file
                    else
                        disp('No variables found in MAT file');
                        return;
                    end
                    
                otherwise
                    disp('Unsupported file type');
                    return;
            end
            % Assign the data to the base workspace
            assignin('base', 'importedData', importedData);
            % Optional: Update a UI component with the imported data
            if istable(importedData)
                app.UITable.Data = importedData;  % Assuming you have a UITable to display tabular data
            elseif isnumeric(importedData) || iscell(importedData)
                % Handle different data types appropriately
                disp('Data imported successfully.');
            end
            % Display a message indicating success
            disp('Data imported and assigned to workspace.');

        end

        % Button pushed function: InterpolateButtonTrb
        function InterpolateButtonTrbPushed(app, event)
            speed = app.NewSpeedTrbEditField.Value;
            Wp4 = evalin('base', 'Wp4');
            Rt = evalin('base', 'Rt');
            efft = evalin('base', 'efft');
            NcPct4 = evalin('base', 'NcPct4');            
            Nrpm4 = evalin('base', 'Nrpm4');
            Np4 = evalin('base', 'Np4');
            if ~ismember(speed, NcPct4)
                Wp4_new = interp1(NcPct4, Wp4, speed);
                Rt_new = interp1(NcPct4,Rt,speed);
                efft_new = interp1(NcPct4,efft,speed);
                insertionNIndex = find(NcPct4 >= speed, 1);
                NcPct4  = [NcPct4(1:insertionNIndex-1), speed, NcPct4(insertionNIndex:end)];
                Nrpm4   = [Nrpm4(1:insertionNIndex-1), speed*Nrpm4(insertionNIndex-1)/NcPct4(insertionNIndex-1), Nrpm4(insertionNIndex:end)];
                Np4     = [Np4(1:insertionNIndex-1), Nrpm4(insertionNIndex)/(Nrpm4(insertionNIndex-1)/Np4(insertionNIndex-1)), Np4(insertionNIndex:end)];
                Wp4 = [Wp4(1:insertionNIndex-1, :); Wp4_new; Wp4(insertionNIndex:end, :)];
                Rt = [Rt(1:insertionNIndex-1, :); Rt_new; Rt(insertionNIndex:end, :)];
                efft = [efft(1:insertionNIndex-1, :); efft_new; efft(insertionNIndex:end, :)];
                plot(app.UIAxesTrb, Wp4_new.*Np4(insertionNIndex)', Rt_new',  '-r','LineWidth',1)
                text(app.UIAxesTrb,Wp4_new(1,1).*Np4(1,1)',Rt_new(1,1)-0.1,num2str(speed),'FontSize', 12, 'Color', 'red', 'HorizontalAlignment', 'center');
                newData = table(Wp4_new', Rt_new', efft_new', 'VariableNames', {'Wp4', 'Rt', 'efft'});
                app.UITableTrb.Data = newData;
                assignin("base",'Wp4',Wp4);
                assignin("base",'NcPct4',NcPct4);
                assignin("base",'Rt',Rt);
                assignin("base",'efft',efft);
                assignin("base",'Nrpm4',Nrpm4);
                assignin("base",'Np4',Np4);
                save('TrbMapUpdated.mat',"Wp4","efft","Rt","NcPct4","Np4","Nrpm4")
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportMapTab = uitab(app.TabGroup);
            app.ImportMapTab.Title = 'Import Map';
            app.ImportMapButton = uibutton(app.ImportMapTab, 'push');
            app.ImportMapButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMapButtonPushed, true);
            app.ImportMapButton.Text = 'Import Map';
            app.CompressorTab = uitab(app.TabGroup);
            app.CompressorTab.Title = 'Compressor ';
            app.UIAxesCmp = uiaxes(app.CompressorTab);
            title(app.UIAxesCmp, 'Compressor Map')
            xlabel(app.UIAxesCmp, 'Mass Flow (kg/s)')
            ylabel(app.UIAxesCmp, 'Pressure Ratio (-)')
            zlabel(app.UIAxesCmp, 'Z')
            app.DropDownCmp = uidropdown(app.CompressorTab);
            app.DropDownCmp.Items = {'Choose File', '400N', '1000N'};
            app.DropDownCmp.ValueChangedFcn = createCallbackFcn(app, @DropDownCmpValueChanged, true);
            app.DropDownCmp.Placeholder = 'Choose File';
            app.DropDownCmp.Value = 'Choose File';
            app.InterpolateButtonCmp = uibutton(app.CompressorTab, 'push');
            app.InterpolateButtonCmp.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButtonCmp.Text = 'Interpolate';
            app.ResetButtonCmp = uibutton(app.CompressorTab, 'push');
            app.ResetButtonCmp.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonCmpPushed, true);
            app.ResetButtonCmp.Text = 'Reset';
            app.SaveButtonCmp = uibutton(app.CompressorTab, 'push');
            app.SaveButtonCmp.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonCmpPushed, true);
            app.SaveButtonCmp.Text = 'Save';
            app.UITableCmp = uitable(app.CompressorTab);
            app.UITableCmp.ColumnName = {'Wp'; 'Rc'; 'effc'};
            app.UITableCmp.RowName = {};
            app.UITableCmp.CellEditCallback = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolatenewspeedLabel = uilabel(app.CompressorTab);
            app.InterpolatenewspeedLabel.Text = {'Interpolate '; 'new speed (%)'};
            app.NewSpeedCmpEditField = uieditfield(app.CompressorTab, 'numeric');
            app.NewSpeedCmpEditField.ValueChangedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.SwitchLabel = uilabel(app.CompressorTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.CompressorTab, 'slider');
            app.TurbineTab = uitab(app.TabGroup);
            app.TurbineTab.Title = 'Turbine';
            app.UIAxesTrb = uiaxes(app.TurbineTab);
            title(app.UIAxesTrb, 'Turbine Map')
            xlabel(app.UIAxesTrb, 'Mass Flow (kg/s)')
            ylabel(app.UIAxesTrb, 'Pressure Ratio (-)')
            zlabel(app.UIAxesTrb, 'Z')
            app.UITableTrb = uitable(app.TurbineTab);
            app.UITableTrb.ColumnName = {'Wp'; 'Rt'; 'efft'};
            app.UITableTrb.RowName = {};
            app.DropDownTrb = uidropdown(app.TurbineTab);
            app.DropDownTrb.Items = {'Choose File', '400N', '1000N'};
            app.DropDownTrb.Placeholder = 'Choose File';
            app.DropDownTrb.Value = 'Choose File';
            app.ResetButtonTrb = uibutton(app.TurbineTab, 'push');
            app.ResetButtonTrb.Text = 'Reset';
            app.SaveButtonTrb = uibutton(app.TurbineTab, 'push');
            app.SaveButtonTrb.Text = 'Save';
            app.InterpolateButtonTrb = uibutton(app.TurbineTab, 'push');
            app.InterpolateButtonTrb.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonTrbPushed, true);
            app.InterpolateButtonTrb.Text = 'Interpolate';
            app.InterpolatenewspeedLabel_2 = uilabel(app.TurbineTab);
            app.InterpolatenewspeedLabel_2.Text = {'Interpolate '; 'new speed (%)'};
            app.NewSpeedTrbEditField = uieditfield(app.TurbineTab, 'numeric');
            app.CMALabel = uilabel(app.UIFigure);
            app.CMALabel.Text = 'CMA';
            app.pLabel = uilabel(app.UIFigure);
            app.pLabel.Text = 'p';
            app.ILabel = uilabel(app.UIFigure);
            app.ILabel.Text = 'I';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tiilogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CMApI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end